classdef Image_Proc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CreatedbyRupakKumarhttpsrupaksworldmeButton  matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        RotateButton             matlab.ui.control.Button
        BWButton_2               matlab.ui.control.Button
        GSButton                 matlab.ui.control.Button
        afternoiseremovalButton  matlab.ui.control.Button
        addnoiseButton           matlab.ui.control.Button
        SelectanImageButton      matlab.ui.control.Button
        UIAxes_6                 matlab.ui.control.UIAxes
        UIAxes_5                 matlab.ui.control.UIAxes
        UIAxes_4                 matlab.ui.control.UIAxes
        UIAxes_3                 matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectanImageButton
        function SelectanImageButtonPushed(app, event)
            global a;
            [filename,pathname] = uigetfile('*.*','pick an image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imshow(a,'Parent',app.UIAxes);
        end

        % Button pushed function: addnoiseButton
        function addnoiseButtonPushed(app, event)
           global a;
           global J;
           J=a;
           J=imnoise(J,'salt & pepper', 0.4);
           imshow(J,'Parent',app.UIAxes_2);
        end

        % Button pushed function: afternoiseremovalButton
        function afternoiseremovalButtonPushed(app, event)
            global J;
            p=J;
            p=medfilt3(J,[5,5,3]);
            imshow(p,'Parent',app.UIAxes_3)
        end

        % Button pushed function: GSButton
        function GSButtonPushed(app, event)
           global a;
           global B;
           B=a;
           B=rgb2gray(a);
           imshow(B,'Parent',app.UIAxes_4); 
        end

        % Button pushed function: BWButton_2
        function BWButton_2Pushed(app, event)
          global a;
           global C;
           C=a;
           C=im2bw(a);
           imshow(C,'Parent',app.UIAxes_5);  
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
        global a;
           global R;
           R=a;
           R=imrotate(a,180);
           imshow(R,'Parent',app.UIAxes_6);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Noisy Image')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Image after removing noise')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Gray Scale')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Black & White')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Rotate  180*')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.SelectanImageButton = uibutton(app.UIFigure, 'push');
            app.SelectanImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectanImageButtonPushed, true);
            app.SelectanImageButton.Text = 'Select an Image';
            app.addnoiseButton = uibutton(app.UIFigure, 'push');
            app.addnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @addnoiseButtonPushed, true);
            app.addnoiseButton.Text = 'add noise';
            app.afternoiseremovalButton = uibutton(app.UIFigure, 'push');
            app.afternoiseremovalButton.ButtonPushedFcn = createCallbackFcn(app, @afternoiseremovalButtonPushed, true);
            app.afternoiseremovalButton.Text = 'after noise removal ';
            app.GSButton = uibutton(app.UIFigure, 'push');
            app.GSButton.ButtonPushedFcn = createCallbackFcn(app, @GSButtonPushed, true);
            app.GSButton.Text = 'G/S';
            app.BWButton_2 = uibutton(app.UIFigure, 'push');
            app.BWButton_2.ButtonPushedFcn = createCallbackFcn(app, @BWButton_2Pushed, true);
            app.BWButton_2.Text = 'B & W';
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Image Processing App'};
            app.CreatedbyRupakKumarhttpsrupaksworldmeButton = uibutton(app.UIFigure, 'push');
            app.CreatedbyRupakKumarhttpsrupaksworldmeButton.Text = {'Created by ~ Rupak Kumar'; '(https://rupaksworld.me)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Proc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end